classdef memGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Y2EditField         matlab.ui.control.NumericEditField
        Y1EditField_2Label  matlab.ui.control.Label
        Z2EditField         matlab.ui.control.NumericEditField
        Z1EditField_2Label  matlab.ui.control.Label
        X2EditField         matlab.ui.control.NumericEditField
        X1EditField_2Label  matlab.ui.control.Label
        MoveButton_2        matlab.ui.control.Button
        Label_5             matlab.ui.control.Label
        MoveButton          matlab.ui.control.Button
        Y1EditField         matlab.ui.control.NumericEditField
        Y1EditFieldLabel    matlab.ui.control.Label
        Z1EditField         matlab.ui.control.NumericEditField
        Z1EditFieldLabel    matlab.ui.control.Label
        X1EditField         matlab.ui.control.NumericEditField
        X1EditFieldLabel    matlab.ui.control.Label
        ResetButton         matlab.ui.control.Button
        Robot2Joint6Label   matlab.ui.control.Label
        Robot2Joint6Slider  matlab.ui.control.Slider
        Joint5Label_2       matlab.ui.control.Label
        Robot2Joint5Slider  matlab.ui.control.Slider
        Joint5Label         matlab.ui.control.Label
        Robot2Joint4Slider  matlab.ui.control.Slider
        Joint4Label         matlab.ui.control.Label
        Robot2Joint3Slider  matlab.ui.control.Slider
        Joint3Label         matlab.ui.control.Label
        Robot2Joint2Slider  matlab.ui.control.Slider
        Joint2Label         matlab.ui.control.Label
        Robot2Joint1Slider  matlab.ui.control.Slider
        Joint1Label         matlab.ui.control.Label
        Robot2Joint5Label   matlab.ui.control.Label
        Robot2Joint4Label   matlab.ui.control.Label
        Robot2Joint3Label   matlab.ui.control.Label
        Robot2Joint2Label   matlab.ui.control.Label
        Robot2Joint1Label   matlab.ui.control.Label
        Robot1Joint5Label   matlab.ui.control.Label
        Robot1Joint4Label   matlab.ui.control.Label
        Robot1Joint3Label   matlab.ui.control.Label
        Robot1Joint2Label   matlab.ui.control.Label
        Robot1Joint1Label   matlab.ui.control.Label
        Robot1Joint5Slider  matlab.ui.control.Slider
        Joint5Label_3       matlab.ui.control.Label
        Robot1Joint4Slider  matlab.ui.control.Slider
        Joint4Label_2       matlab.ui.control.Label
        Robot1Joint3Slider  matlab.ui.control.Slider
        Joint3Label_2       matlab.ui.control.Label
        Robot1Joint2Slider  matlab.ui.control.Slider
        Joint2Label_2       matlab.ui.control.Label
        Robot1Joint1Slider  matlab.ui.control.Slider
        Joint1Label_2       matlab.ui.control.Label
        OperationRunButton  matlab.ui.control.Button
        ResumeButton        matlab.ui.control.Button
        EStopButton         matlab.ui.control.Button
        InitialiseButton    matlab.ui.control.Button
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
    end

    
    properties (Access = public)
        SeggyDsIn % Description
        Robot1JointAngles
        Robot2JointAngles
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function results = updateRobot1Visualization(app)
            app.SeggyDsIn.updateRobot1(app.Robot1JointAngles);
        end

        function results = updateRobot2Visualization(app)
            app.SeggyDsIn.updateRobot2(app.Robot2JointAngles);
        end
    end
    
    methods (Access = private)
        
         function results = updateLabels(app)
             app.Robot1JointAngles = deg2rad([-90, 5, 85, 50, 0]);
            app.Robot2JointAngles = deg2rad([90, -90, 90, -90, -90, 0]);

            app.Robot1Joint1Slider.Value = rad2deg(app.Robot1JointAngles(1)); % Convert
            app.Robot1Joint1Label.Text = sprintf('%d°', round(rad2deg(app.Robot1JointAngles(1))));

            app.Robot1Joint2Slider.Value = rad2deg(app.Robot1JointAngles(2)); % Convert
            app.Robot1Joint2Label.Text = sprintf('%d°', round(rad2deg(app.Robot1JointAngles(2))));

            app.Robot1Joint3Slider.Value = rad2deg(app.Robot1JointAngles(3)); % Convert
            app.Robot1Joint3Label.Text = sprintf('%d°', round(rad2deg(app.Robot1JointAngles(3))));

            app.Robot1Joint4Slider.Value = rad2deg(app.Robot1JointAngles(4)); % Convert
            app.Robot1Joint4Label.Text = sprintf('%d°', round(rad2deg(app.Robot1JointAngles(4))));

            app.Robot1Joint5Slider.Value = rad2deg(app.Robot1JointAngles(5)); % Convert
            app.Robot1Joint5Label.Text = sprintf('%d°', round(rad2deg(app.Robot1JointAngles(5))));


            app.Robot2Joint1Slider.Value = rad2deg(app.Robot2JointAngles(1)); % Convert
            app.Robot2Joint1Label.Text = sprintf('%d°', round(rad2deg(app.Robot2JointAngles(1))));

            app.Robot2Joint2Slider.Value = rad2deg(app.Robot2JointAngles(2)); % Convert
            app.Robot2Joint2Label.Text = sprintf('%d°', round(rad2deg(app.Robot2JointAngles(2))));

            app.Robot2Joint3Slider.Value = rad2deg(app.Robot2JointAngles(3)); % Convert
            app.Robot2Joint3Label.Text = sprintf('%d°', round(rad2deg(app.Robot2JointAngles(3))));

            app.Robot2Joint4Slider.Value = rad2deg(app.Robot2JointAngles(4)); % Convert
            app.Robot2Joint4Label.Text = sprintf('%d°', round(rad2deg(app.Robot2JointAngles(4))));

            app.Robot2Joint5Slider.Value = rad2deg(app.Robot2JointAngles(5)); % Convert
            app.Robot2Joint5Label.Text = sprintf('%d°', round(rad2deg(app.Robot2JointAngles(5))));
         end

         function disableSliders(app)
             % Disable all sliders
             sliders = [app.Robot1Joint1Slider, app.Robot1Joint2Slider, app.Robot1Joint3Slider, ...
                 app.Robot1Joint4Slider, app.Robot1Joint5Slider, app.Robot2Joint1Slider, ...
                 app.Robot2Joint2Slider, app.Robot2Joint3Slider, app.Robot2Joint4Slider, ...
                 app.Robot2Joint5Slider, app.Robot2Joint6Slider];
             for i = 1:length(sliders)
                 sliders(i).Enable = 'off';
             end
         end

         function enableSliders(app)
             % Enable all sliders
             sliders = [app.Robot1Joint1Slider, app.Robot1Joint2Slider, app.Robot1Joint3Slider, ...
                 app.Robot1Joint4Slider, app.Robot1Joint5Slider, app.Robot2Joint1Slider, ...
                 app.Robot2Joint2Slider, app.Robot2Joint3Slider, app.Robot2Joint4Slider, ...
                 app.Robot2Joint5Slider, app.Robot2Joint6Slider];
             for i = 1:length(sliders)
                 sliders(i).Enable = 'on';
             end
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            app.SeggyDsIn = SeggyDs();
            app.Robot1JointAngles = deg2rad([-90, 5, 85, 50, 0]);
            app.Robot2JointAngles = deg2rad([90, -90, 90, -90, -90, 0]);
            % app.Robot1JointAngles = [0,pi/4,pi/4,pi/2,0];
            app.updateRobot1Visualization();
            app.updateRobot2Visualization();
            app.updateLabels();
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.SeggyDsIn.flipEStop();
            if app.SeggyDsIn.estopFlag
                disableSliders(app);
                app.MoveButton.Enable = 'off'; % Disable move button 1
                app.MoveButton_2.Enable = 'off'; % Disable move button 2
                app.EStopButton.BackgroundColor = [1 0.4 0.4];  % Red color
            end
            if ~app.SeggyDsIn.estopFlag
                enableSliders(app);
                app.MoveButton.Enable = 'on'; % Enable move button 1
                app.MoveButton_2.Enable = 'on'; % Enable move button 2
                app.EStopButton.BackgroundColor = [0.47 0.67 0.19];  % Green color
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.SeggyDsIn.operationRecovery();
        end

        % Button pushed function: OperationRunButton
        function OperationRunButtonPushed(app, event)
            app.SeggyDsIn.OperationRun();
        end

        % Value changing function: Robot1Joint1Slider
        function Robot1Joint1SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot1JointAngles(1) = radianValue; % Update joint angle
            app.Robot1Joint1Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot1Visualization(); % Update the robot plot
        end

        % Value changing function: Robot1Joint2Slider
        function Robot1Joint2SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot1JointAngles(2) = radianValue; % Update joint angle
            app.Robot1Joint2Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot1Visualization(); % Update the robot plot
        end

        % Value changing function: Robot1Joint3Slider
        function Robot1Joint3SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot1JointAngles(3) = radianValue; % Update joint angle
            app.Robot1Joint3Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot1Visualization(); % Update the robot plot
        end

        % Value changing function: Robot1Joint4Slider
        function Robot1Joint4SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot1JointAngles(4) = radianValue; % Update joint angle
            app.Robot1Joint4Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot1Visualization(); % Update the robot plot
        end

        % Value changing function: Robot1Joint5Slider
        function Robot1Joint5SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot1JointAngles(5) = radianValue; % Update joint angle
            app.Robot1Joint5Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot1Visualization(); % Update the robot plot
        end

        % Value changing function: Robot2Joint1Slider
        function Robot2Joint1SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot2JointAngles(1) = radianValue; % Update joint angle
            app.Robot2Joint1Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot2Visualization(); % Update the robot plot
        end

        % Value changing function: Robot2Joint2Slider
        function Robot2Joint2SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot2JointAngles(2) = radianValue; % Update joint angle
            app.Robot2Joint2Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot2Visualization(); % Update the robot plot
        end

        % Value changing function: Robot2Joint3Slider
        function Robot2Joint3SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot2JointAngles(3) = radianValue; % Update joint angle
            app.Robot2Joint3Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot2Visualization(); % Update the robot plot
        end

        % Value changing function: Robot2Joint4Slider
        function Robot2Joint4SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot2JointAngles(4) = radianValue; % Update joint angle
            app.Robot2Joint4Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot2Visualization(); % Update the robot plot
        end

        % Value changing function: Robot2Joint5Slider
        function Robot2Joint5SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot2JointAngles(5) = radianValue; % Update joint angle
            app.Robot2Joint5Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot2Visualization(); % Update the robot plot
        end

        % Value changing function: Robot2Joint6Slider
        function Robot2Joint6SliderValueChanging(app, event)
            radianValue = deg2rad(event.Value);
            app.Robot2JointAngles(6) = radianValue; % Update joint angle
            app.Robot2Joint6Label.Text = sprintf('%d°', round(event.Value));
            app.updateRobot2Visualization(); % Update the robot plot
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SeggyDsIn.MoveToReady;
            app.updateLabels();
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            x1 = app.X1EditField.Value;
            y1 = app.Y1EditField.Value;
            z1 = app.Z1EditField.Value;

            app.SeggyDsIn.moveTo1(x1, y1, z1);
        end

        % Button pushed function: MoveButton_2
        function MoveButton_2Pushed(app, event)
            x2 = app.X2EditField.Value;
            y2 = app.Y2EditField.Value;
            z2 = app.Z2EditField.Value;

            app.SeggyDsIn.moveTo2(x2, y2, z2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.InitialiseButton = uibutton(app.UIFigure, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.OperationRunButton = uibutton(app.UIFigure, 'push');
            app.OperationRunButton.ButtonPushedFcn = createCallbackFcn(app, @OperationRunButtonPushed, true);
            app.OperationRunButton.Text = 'OperationRun';
            app.Joint1Label_2 = uilabel(app.UIFigure);
            app.Joint1Label_2.Text = 'Joint 1';
            app.Robot1Joint1Slider = uislider(app.UIFigure);
            app.Robot1Joint1Slider.Limits = [-135 135];
            app.Robot1Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Robot1Joint1SliderValueChanging, true);
            app.Joint2Label_2 = uilabel(app.UIFigure);
            app.Joint2Label_2.Text = 'Joint 2';
            app.Robot1Joint2Slider = uislider(app.UIFigure);
            app.Robot1Joint2Slider.Limits = [5 80];
            app.Robot1Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Robot1Joint2SliderValueChanging, true);
            app.Robot1Joint2Slider.Value = 45;
            app.Joint3Label_2 = uilabel(app.UIFigure);
            app.Joint3Label_2.Text = 'Joint 3';
            app.Robot1Joint3Slider = uislider(app.UIFigure);
            app.Robot1Joint3Slider.Limits = [-5 85];
            app.Robot1Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Robot1Joint3SliderValueChanging, true);
            app.Robot1Joint3Slider.Value = 45;
            app.Joint4Label_2 = uilabel(app.UIFigure);
            app.Joint4Label_2.Text = 'Joint 4';
            app.Robot1Joint4Slider = uislider(app.UIFigure);
            app.Robot1Joint4Slider.Limits = [-180 180];
            app.Robot1Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Robot1Joint4SliderValueChanging, true);
            app.Robot1Joint4Slider.Value = 90;
            app.Joint5Label_3 = uilabel(app.UIFigure);
            app.Joint5Label_3.Text = 'Joint 5';
            app.Robot1Joint5Slider = uislider(app.UIFigure);
            app.Robot1Joint5Slider.Limits = [-85 85];
            app.Robot1Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Robot1Joint5SliderValueChanging, true);
            app.Robot1Joint1Label = uilabel(app.UIFigure);
            app.Robot1Joint1Label.Text = '0';
            app.Robot1Joint2Label = uilabel(app.UIFigure);
            app.Robot1Joint2Label.Text = '0';
            app.Robot1Joint3Label = uilabel(app.UIFigure);
            app.Robot1Joint3Label.Text = '0';
            app.Robot1Joint4Label = uilabel(app.UIFigure);
            app.Robot1Joint4Label.Text = '0';
            app.Robot1Joint5Label = uilabel(app.UIFigure);
            app.Robot1Joint5Label.Text = '0';
            app.Robot2Joint1Label = uilabel(app.UIFigure);
            app.Robot2Joint1Label.Text = '0';
            app.Robot2Joint2Label = uilabel(app.UIFigure);
            app.Robot2Joint2Label.Text = '0';
            app.Robot2Joint3Label = uilabel(app.UIFigure);
            app.Robot2Joint3Label.Text = '0';
            app.Robot2Joint4Label = uilabel(app.UIFigure);
            app.Robot2Joint4Label.Text = '0';
            app.Robot2Joint5Label = uilabel(app.UIFigure);
            app.Robot2Joint5Label.Text = '0';
            app.Joint1Label = uilabel(app.UIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.Robot2Joint1Slider = uislider(app.UIFigure);
            app.Robot2Joint1Slider.Limits = [-360 360];
            app.Robot2Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Robot2Joint1SliderValueChanging, true);
            app.Joint2Label = uilabel(app.UIFigure);
            app.Joint2Label.Text = 'Joint 2';
            app.Robot2Joint2Slider = uislider(app.UIFigure);
            app.Robot2Joint2Slider.Limits = [-90 90];
            app.Robot2Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Robot2Joint2SliderValueChanging, true);
            app.Robot2Joint2Slider.Value = 45;
            app.Joint3Label = uilabel(app.UIFigure);
            app.Joint3Label.Text = 'Joint 3';
            app.Robot2Joint3Slider = uislider(app.UIFigure);
            app.Robot2Joint3Slider.Limits = [-170 170];
            app.Robot2Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Robot2Joint3SliderValueChanging, true);
            app.Robot2Joint3Slider.Value = 45;
            app.Joint4Label = uilabel(app.UIFigure);
            app.Joint4Label.Text = 'Joint 4';
            app.Robot2Joint4Slider = uislider(app.UIFigure);
            app.Robot2Joint4Slider.Limits = [-360 360];
            app.Robot2Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Robot2Joint4SliderValueChanging, true);
            app.Robot2Joint4Slider.Value = 90;
            app.Joint5Label = uilabel(app.UIFigure);
            app.Joint5Label.Text = 'Joint 5';
            app.Robot2Joint5Slider = uislider(app.UIFigure);
            app.Robot2Joint5Slider.Limits = [-360 360];
            app.Robot2Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Robot2Joint5SliderValueChanging, true);
            app.Joint5Label_2 = uilabel(app.UIFigure);
            app.Joint5Label_2.Text = 'Joint 6';
            app.Robot2Joint6Slider = uislider(app.UIFigure);
            app.Robot2Joint6Slider.Limits = [-360 360];
            app.Robot2Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Robot2Joint6SliderValueChanging, true);
            app.Robot2Joint6Label = uilabel(app.UIFigure);
            app.Robot2Joint6Label.Text = '0';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditFieldLabel = uilabel(app.UIFigure);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.MoveButton_2 = uibutton(app.UIFigure, 'push');
            app.MoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_2Pushed, true);
            app.MoveButton_2.Text = 'Move';
            app.X1EditField_2Label = uilabel(app.UIFigure);
            app.X1EditField_2Label.Text = 'X1';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField_2Label = uilabel(app.UIFigure);
            app.Z1EditField_2Label.Text = 'Z1';
            app.Z2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditField_2Label = uilabel(app.UIFigure);
            app.Y1EditField_2Label.Text = 'Y1';
            app.Y2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = memGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end